classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button_types       matlab.ui.container.ButtonGroup
        button_pmcov       matlab.ui.control.ToggleButton
        button_pcov        matlab.ui.control.ToggleButton
        button_pburg       matlab.ui.control.ToggleButton
        button_pyulear     matlab.ui.control.ToggleButton
        Button_3           matlab.ui.control.Button
        FormatButtonGroup  matlab.ui.container.ButtonGroup
        LinButton          matlab.ui.control.RadioButton
        LogButton          matlab.ui.control.RadioButton
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        UIAxes7            matlab.ui.control.UIAxes
        UIAxes6            matlab.ui.control.UIAxes
        UIAxes4            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x % Description
        Fs % Description
        y % Description
        t
        tmax
        T
        N
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app)
            app.Fs = 250;
        end

        % Button pushed function: Button
        function Button_signal(app, event)
            f = uigetfile('*.txt');
            app.x = load(f);
            app.N = length(app.x);
            app.T = 1/app.Fs;
            app.tmax = app.N * app.T;
            app.t=0:app.T:app.tmax-app.T;
            plot(app.UIAxes, app.t, app.x)
        end

        % Button pushed function: Button_2
        function Button_rasshet(app, event)
            
            load("coef.mat");           % загрузка коэффициентов
            app.y = filter(b, a, app.x);    % фильтрация
            
            plot(app.UIAxes2, app.t,app.y);        % вывод сигнала после фильтра

            %спектральная мощность
            plot(app.UIAxes3,pyulear(app.x, 10))
            plot(app.UIAxes4,pyulear(app.y, 10))

            %автокорр
            acf1 = xcorr(app.x, 'coeff');
            tacf1=-app.tmax+app.T:app.T:app.tmax-app.T;
            plot(app.UIAxes6,tacf1,acf1)
            set(app.UIAxes6, 'Xlim',[-2.5 2.5])

            acf2 = xcorr(app.y, 'coeff');
            tacf2 =-app.tmax+app.T:app.T:app.tmax-app.T;
            plot(app.UIAxes7,tacf2,acf2)
            set(app.UIAxes7, 'Xlim',[-2.5 2.5])

        end

        % Selection changed function: Button_types
        function Button_typesSelectionChanged(app, event)
            selectedButton = app.Button_types.SelectedObject;
            if app.button_pyulear.Value
                if app.LinButton.Value
                    plot(app.UIAxes3,pyulear(app.x, 10))
                    plot(app.UIAxes4,pyulear(app.y, 10))
                else
                    plot(app.UIAxes3,20*log10(pyulear(app.x, 10)))
                    plot(app.UIAxes4,20*log10(pyulear(app.y, 10)))
                end
            end
            if app.button_pburg.Value
                if app.LinButton.Value
                    plot(app.UIAxes3,pburg(app.x, 10))
                    plot(app.UIAxes4,pburg(app.y, 10))
                else
                    plot(app.UIAxes3,20*log10(pburg(app.x, 10)))
                    plot(app.UIAxes4,20*log10(pburg(app.y, 10)))
                end
            end
            if app.button_pcov.Value
                if app.LinButton.Value
                    plot(app.UIAxes3,pcov(app.x, 10))
                    plot(app.UIAxes4,pcov(app.y, 10))
                else
                    plot(app.UIAxes3,20*log10(pcov(app.x, 10)))
                    plot(app.UIAxes4,20*log10(pcov(app.y, 10)))
                end
            end
            if app.button_pmcov.Value
                if app.LinButton.Value
                    plot(app.UIAxes3,pmcov(app.x, 10))
                    plot(app.UIAxes4,pmcov(app.y, 10))
                else
                    plot(app.UIAxes3,20*log10(pmcov(app.x, 10)))
                    plot(app.UIAxes4,20*log10(pmcov(app.y, 10)))
                end
            end
        end

        % Button pushed function: Button_3
        function Button_exit(app, event)
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Сигнал до фильтрации')
            xlabel(app.UIAxes, 't, c')
            ylabel(app.UIAxes, 'ЭЭГ, mV')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [-40 40];
            app.UIAxes.YTick = [-40 0 40];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Сигнал после фильтрации')
            xlabel(app.UIAxes2, 't, c')
            ylabel(app.UIAxes2, 'ЭЭГ, mV')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 5];
            app.UIAxes2.YLim = [-40 40];
            app.UIAxes2.XTick = [0 1 2 3 4 5];
            app.UIAxes2.YTick = [-40 0 40];
            app.UIAxes2.YTickLabel = {'-40'; '0'; '40'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'СПМ до фильтрации')
            xlabel(app.UIAxes3, 'Гц')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'СПМ после фильтрации')
            xlabel(app.UIAxes4, 'Гц')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Оценка до фильтрации')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XLim = [-1250 1250];
            app.UIAxes6.YLim = [-1 1];
            app.UIAxes6.ZLim = [-1 1];
            app.UIAxes6.YTick = [-1 0 1];
            app.UIAxes6.YTickLabel = {'-1'; '0'; '1'};
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Оценка после фильтрации')
            ylabel(app.UIAxes7, {''; ''})
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.XLim = [-1250 1250];
            app.UIAxes7.YLim = [-1 1];
            app.UIAxes7.YTick = [-1 0 1];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_signal, true);
            app.Button.Text = 'Сигнал';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_rasshet, true);
            app.Button_2.Text = 'Расчет';
            app.FormatButtonGroup = uibuttongroup(app.UIFigure);
            app.FormatButtonGroup.Title = 'Format';
            app.LogButton = uiradiobutton(app.FormatButtonGroup);
            app.LogButton.Text = {'Log'; ''};
            app.LogButton.Value = true;
            app.LinButton = uiradiobutton(app.FormatButtonGroup);
            app.LinButton.Text = {'Lin'; ''};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_exit, true);
            app.Button_3.Text = 'Выход';
            app.Button_types = uibuttongroup(app.UIFigure);
            app.Button_types.SelectionChangedFcn = createCallbackFcn(app, @Button_typesSelectionChanged, true);
            app.Button_types.Title = 'Button Group';
            app.button_pyulear = uitogglebutton(app.Button_types);
            app.button_pyulear.Text = 'Юл-Уокер';
            app.button_pyulear.Value = true;
            app.button_pburg = uitogglebutton(app.Button_types);
            app.button_pburg.Text = 'Берг';
            app.button_pcov = uitogglebutton(app.Button_types);
            app.button_pcov.Text = 'Ковариац.';
            app.button_pmcov = uitogglebutton(app.Button_types);
            app.button_pmcov.Text = 'Мод.ковар.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end